classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        mangoconditionsLabel          matlab.ui.control.Label
        humidity_inSpinnerLabel       matlab.ui.control.Label
        humidity_inSpinner            matlab.ui.control.Spinner
        humidity_outSpinnerLabel      matlab.ui.control.Label
        humidity_outSpinner           matlab.ui.control.Spinner
        airconditionsLabel            matlab.ui.control.Label
        temperature_airCSpinnerLabel  matlab.ui.control.Label
        temperature_airCSpinner       matlab.ui.control.Spinner
        humidity_airSpinnerLabel      matlab.ui.control.Label
        humidity_airSpinner           matlab.ui.control.Spinner
        ovensettingsLabel             matlab.ui.control.Label
        oven_typeDropDownLabel        matlab.ui.control.Label
        oven_typeDropDown             matlab.ui.control.DropDown
        temperature_inCSpinnerLabel   matlab.ui.control.Label
        temperature_inCSpinner        matlab.ui.control.Spinner
        temperature_outCSpinnerLabel  matlab.ui.control.Label
        temperature_outCSpinner       matlab.ui.control.Spinner
        standardvaluesButton          matlab.ui.control.Button
        calculateButton               matlab.ui.control.Button
        superheatingCSpinnerLabel     matlab.ui.control.Label
        superheatingCSpinner          matlab.ui.control.Spinner
        CenterPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        E_per_kgkJLabel               matlab.ui.control.Label
        Label_energySecondary         matlab.ui.control.Label
        air_per_kgm3Label             matlab.ui.control.Label
        Label_AirFlow                 matlab.ui.control.Label
        secondaryenergyperkgdrymangoLabel  matlab.ui.control.Label
        primaryenergyperkgdrymangoLabel  matlab.ui.control.Label
        E_per_kgkJLabel_2             matlab.ui.control.Label
        Label_energyPrimary           matlab.ui.control.Label
        transportationairneededperkgdrymangoLabel  matlab.ui.control.Label
        carbondioxideemittedperkgdrymangoLabel  matlab.ui.control.Label
        energySourceLabel             matlab.ui.control.Label
        Label_energySource            matlab.ui.control.Label
        CO2kgLabel                    matlab.ui.control.Label
        Label_CO2weight               matlab.ui.control.Label
        save_plotButton               matlab.ui.control.Button
        save_CSVButton                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = public)
        def % variable to store the default values
        energy_per_kg_mango % stores the actual SecondaryEnergy
        air_per_kg_mango % stores the actual air value
    end
    
    methods (Access = private)
        function standardValues(app)
            switch app.oven_typeDropDown.Value
                case 'idealConvective'
                    %mango conditions
                    app.humidity_inSpinner.Value        = app.def.man_w_c_in*100;
                    app.humidity_outSpinner.Value       = app.def.man_w_c_out*100;
                    %air-conditions
                    app.temperature_airCSpinner.Value   = app.def.s_air_t_cels;
                    app.humidity_airSpinner.Value       = app.def.s_air_humidity*100;
                    %oven-settings
                    app.temperature_inCSpinner.Value    = app.def.air_in_t_cels;
                    app.temperature_outCSpinner.Value   = app.def.air_out_t_cels;
                    app.superheatingCSpinner.Value      = 0;
                case 'idealCondensation'
                    %mango conditions
                    app.humidity_inSpinner.Value        = app.def.man_w_c_in*100;
                    app.humidity_outSpinner.Value       = app.def.man_w_c_out*100;
                    %air-conditions
                    app.temperature_airCSpinner.Value   = 0;
                    app.humidity_airSpinner.Value       = app.def.conden_air_hum*100;
                    %oven-settings
                    app.temperature_inCSpinner.Value    = app.def.conden_t_heating;
                    app.temperature_outCSpinner.Value   = app.def.conden_t_cooling;
                    app.superheatingCSpinner.Value      = app.def.conden_superheating;
            end
        end
        
        function draw_psych_base(app)
            fig = openfig('storage/psychplot_base.fig', 'invisible');
            ax  = findobj(fig,'Type','Axes');
            copyobj(ax.Children, app.UIAxes);
            delete(fig);
            app.UIAxes.YLim             = [0, 35];
            app.UIAxes.YAxisLocation    = 'right';
            app.UIAxes.YLabel.String    = 'specific humidity [g/kg]';
            app.UIAxes.XLabel.String    = 'temperature [°C]';
        end
        
        function draw_psych(app)
            f = waitbar(0, 'starting calculations');
            
            hold(app.UIAxes, "off");
            
            waitbar(0.3, f, 'calculate specific data');
            switch app.oven_typeDropDown.Value
                case 'idealConvective'
                    [app.energy_per_kg_mango, app.air_per_kg_mango] = ...
                    idealDrying(app.UIAxes, app.humidity_inSpinner.Value, app.humidity_outSpinner.Value, ...
                    app.temperature_airCSpinner.Value, app.humidity_airSpinner.Value, ...
                    app.temperature_inCSpinner.Value, app.temperature_outCSpinner.Value);
                case 'idealCondensation'
                    [app.energy_per_kg_mango, app.air_per_kg_mango] = ...
                    idealCondensation(app.temperature_outCSpinner.Value, app.temperature_inCSpinner.Value, ...
                    app.superheatingCSpinner.Value, app.humidity_inSpinner.Value, ...
                    app.humidity_outSpinner.Value, app.humidity_airSpinner.Value, app.UIAxes);
            end
            % Wrong user Input
            if app.energy_per_kg_mango == 0
                close(f);
                return
            end
            
            waitbar(0.6, f, 'output of specific data')
            outputSpecificData(app);
            
            waitbar(0.8, f, 'draw psychrometric chart')
            hold(app.UIAxes, "on");
            draw_psych_base(app);
            
            waitbar(1, f, 'finishing')
            close(f);
        end
        
        function outputSpecificData(app)
            app.Label_AirFlow.Text          = num2str(round(app.air_per_kg_mango));
            switch app.oven_typeDropDown.Value
                case 'idealConvective'
                    [energySecondary, energyPrimary, energyButane, CO2weight] = specificIdealConvective(app);
                    app.Label_energySecondary.Text  = num2str(energySecondary);
                    app.Label_energyPrimary.Text    = num2str(energyPrimary);
                    app.Label_energySource.Text     = num2str(energyButane);
                    app.Label_CO2weight.Text        = num2str(CO2weight);
                case 'idealCondensation'
                    [energySecondary, energyPrimary, energyElectricity, CO2weight] = specificIdealCondensation(app);
                    app.Label_energySecondary.Text  = num2str(energySecondary);
                    app.Label_energyPrimary.Text    = num2str(energyPrimary);
                    app.Label_energySource.Text     = num2str(energyElectricity);
                    app.Label_CO2weight.Text        = num2str(CO2weight);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.def = load('defaults.mat');
            standardValues(app);
            draw_psych(app);
            
            % logging status information
            logFileGenerator('started session');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % logging status information
            logFileGenerator('finished session');
            
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: standardvaluesButton
        function standardvaluesButtonPushed(app, event)
            standardValues(app);
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            draw_psych(app);
        end

        % Button pushed function: save_plotButton
        function save_plotButtonPushed(app, event)
            drawPlot(app);
        end

        % Value changed function: oven_typeDropDown
        function oven_typeDropDownValueChanged(app, event)
            value = app.oven_typeDropDown.Value;
            switch value
                case 'idealConvective'
                    % input text
                    app.temperature_airCSpinner.Enable      = 'on';
                    app.temperature_airCSpinnerLabel.Enable = 'on';
                    app.temperature_inCSpinnerLabel.Text    = 'temperature_in [°C]';
                    app.temperature_outCSpinnerLabel.Text   = 'temperature_out [°C]';
                    app.superheatingCSpinner.Enable         = 'off';
                    app.superheatingCSpinnerLabel.Enable    = 'off';
                    % output text
                    app.energySourceLabel.Text              = 'Butane [kg]';
                    % load and calculate values
                    standardValues(app);
                    draw_psych(app);
                case 'idealCondensation'
                    % input text
                    app.temperature_airCSpinner.Enable      = 'off';
                    app.temperature_airCSpinnerLabel.Enable = 'off';
                    app.temperature_inCSpinnerLabel.Text    = 'temp_heating [°C]';
                    app.temperature_outCSpinnerLabel.Text   = 'temp_cooling [°C]';
                    app.superheatingCSpinner.Enable         = 'on';
                    app.superheatingCSpinnerLabel.Enable    = 'on';
                    % output text
                    app.energySourceLabel.Text              = 'Electricity [kWh]';
                    % load and calculate values
                    standardValues(app);
                    draw_psych(app);
            end
        end

        % Button pushed function: save_CSVButton
        function save_CSVButtonPushed(app, event)
            exportCSV(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.mangoconditionsLabel = uilabel(app.GridLayout2);
            app.mangoconditionsLabel.Text = 'mango-conditions';
            app.humidity_inSpinnerLabel = uilabel(app.GridLayout2);
            app.humidity_inSpinnerLabel.Text = 'humidity_in [%]';
            app.humidity_inSpinner = uispinner(app.GridLayout2);
            app.humidity_inSpinner.Limits = [0 100];
            app.humidity_outSpinnerLabel = uilabel(app.GridLayout2);
            app.humidity_outSpinnerLabel.Text = 'humidity_out [%]';
            app.humidity_outSpinner = uispinner(app.GridLayout2);
            app.humidity_outSpinner.Limits = [0 100];
            app.airconditionsLabel = uilabel(app.GridLayout2);
            app.airconditionsLabel.Text = 'air-conditions';
            app.temperature_airCSpinnerLabel = uilabel(app.GridLayout2);
            app.temperature_airCSpinnerLabel.Text = 'temperature_air [°C]';
            app.temperature_airCSpinner = uispinner(app.GridLayout2);
            app.temperature_airCSpinner.Limits = [0 60];
            app.humidity_airSpinnerLabel = uilabel(app.GridLayout2);
            app.humidity_airSpinnerLabel.Text = 'humidity_air [%]';
            app.humidity_airSpinner = uispinner(app.GridLayout2);
            app.humidity_airSpinner.Limits = [0 100];
            app.ovensettingsLabel = uilabel(app.GridLayout2);
            app.ovensettingsLabel.Text = 'oven-settings';
            app.oven_typeDropDownLabel = uilabel(app.GridLayout2);
            app.oven_typeDropDownLabel.Text = 'oven_type';
            app.oven_typeDropDown = uidropdown(app.GridLayout2);
            app.oven_typeDropDown.Items = {'idealConvective', 'idealCondensation'};
            app.oven_typeDropDown.ValueChangedFcn = createCallbackFcn(app, @oven_typeDropDownValueChanged, true);
            app.oven_typeDropDown.Value = 'idealConvective';
            app.temperature_inCSpinnerLabel = uilabel(app.GridLayout2);
            app.temperature_inCSpinnerLabel.Text = 'temperature_in [°C]';
            app.temperature_inCSpinner = uispinner(app.GridLayout2);
            app.temperature_inCSpinner.Limits = [0 90];
            app.temperature_outCSpinnerLabel = uilabel(app.GridLayout2);
            app.temperature_outCSpinnerLabel.Text = 'temperature_out [°C]';
            app.temperature_outCSpinner = uispinner(app.GridLayout2);
            app.temperature_outCSpinner.Limits = [0 90];
            app.standardvaluesButton = uibutton(app.GridLayout2, 'push');
            app.standardvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @standardvaluesButtonPushed, true);
            app.standardvaluesButton.Text = 'standard values';
            app.calculateButton = uibutton(app.GridLayout2, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.superheatingCSpinnerLabel = uilabel(app.GridLayout2);
            app.superheatingCSpinnerLabel.Enable = 'off';
            app.superheatingCSpinnerLabel.Text = 'superheating [°C]';
            app.superheatingCSpinner = uispinner(app.GridLayout2);
            app.superheatingCSpinner.Limits = [0 90];
            app.superheatingCSpinner.Enable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'psychrometric chart of drying process')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.E_per_kgkJLabel = uilabel(app.GridLayout4);
            app.E_per_kgkJLabel.Text = 'E_per_kg [kJ]';
            app.Label_energySecondary = uilabel(app.GridLayout4);
            app.Label_energySecondary.Text = '';
            app.air_per_kgm3Label = uilabel(app.GridLayout4);
            app.air_per_kgm3Label.Text = 'air_per_kg [m3]';
            app.Label_AirFlow = uilabel(app.GridLayout4);
            app.Label_AirFlow.Text = '';
            app.secondaryenergyperkgdrymangoLabel = uilabel(app.GridLayout4);
            app.secondaryenergyperkgdrymangoLabel.Text = {'secondary energy'; 'per kg dry mango'};
            app.primaryenergyperkgdrymangoLabel = uilabel(app.GridLayout4);
            app.primaryenergyperkgdrymangoLabel.Text = {'primary energy'; 'per kg dry mango'};
            app.E_per_kgkJLabel_2 = uilabel(app.GridLayout4);
            app.E_per_kgkJLabel_2.Text = 'E_per_kg [kJ]';
            app.Label_energyPrimary = uilabel(app.GridLayout4);
            app.Label_energyPrimary.Text = '';
            app.transportationairneededperkgdrymangoLabel = uilabel(app.GridLayout4);
            app.transportationairneededperkgdrymangoLabel.Text = {'transportation air needed'; 'per kg dry mango'};
            app.carbondioxideemittedperkgdrymangoLabel = uilabel(app.GridLayout4);
            app.carbondioxideemittedperkgdrymangoLabel.Text = {'carbondioxide emitted'; 'per kg dry mango'};
            app.energySourceLabel = uilabel(app.GridLayout4);
            app.energySourceLabel.Text = 'Butane [kg]';
            app.Label_energySource = uilabel(app.GridLayout4);
            app.Label_energySource.Text = '';
            app.CO2kgLabel = uilabel(app.GridLayout4);
            app.CO2kgLabel.Text = 'CO2 [kg]';
            app.Label_CO2weight = uilabel(app.GridLayout4);
            app.Label_CO2weight.Text = '';
            app.save_plotButton = uibutton(app.GridLayout4, 'push');
            app.save_plotButton.ButtonPushedFcn = createCallbackFcn(app, @save_plotButtonPushed, true);
            app.save_plotButton.Text = 'save_plot';
            app.save_CSVButton = uibutton(app.GridLayout4, 'push');
            app.save_CSVButton.ButtonPushedFcn = createCallbackFcn(app, @save_CSVButtonPushed, true);
            app.save_CSVButton.Text = 'save_CSV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end